classdef TestRobotDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PoseDropDownLabel       matlab.ui.control.Label
        PoseDropDown            matlab.ui.control.DropDown
        Joint1SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
        CreateRobotButton       matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        RangeTestButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % main window app object
        DhPara
        qlim
        robot
        arduino_hardware = [];
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        % some useful poses
        Pose = [0 0 0;          %Pose1
                0 90 0;       %Pose2
                0 -90 0;      %Pose3
                90 0 0;       %Pose4
                90 90 0;    %Pose5
                90 -90 0;   %Pose6
                -90 0 0;      %Pose7
                -90 90 0;    %Pose8
                -90 -90 0];  %Pose9
    end
    
    methods (Access = private)
        function updateRobot(app,q)
            if isempty(app.robot) == false
                %Re-plot robot for new pose
                app.robot.plot(q)
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.robot.fkine(q);
                %Update the EE position on GUI
                app.XEditField.Value = T.t(1);
                app.YEditField.Value = T.t(2);
                app.ZEditField.Value = T.t(3);
                %Calculate the distance of EE and update on GUI
                app.DistanceEditField.Value = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
                
                %Control motor
                controlMotor(app,q);
            else
                msgbox("Robot not created yet")
            end
        end
        
        function controlMotor(app,q)
            %control motor to requested angle
            % q: requested angle vector
            if isempty(app.JointServo1) == false
                %clip data between joint limits
                angle1 = clip(rad2deg(q(1)),app.qlim(1,1),app.qlim(1,2));

                %change degree to servo input range(0,1)
                angle1 = (app.qlim(1,2) - angle1)/(app.qlim(1,2) - app.qlim(1,1));
                %clip the data between 0 and 1
                angle1 = clip(angle1,0,1);
                %control servo to right angle
                writePosition(app.JointServo1, angle1);
            end

            if isempty(app.JointServo2) == false
                %clip data between joint limits
                angle2 = clip(rad2deg(q(2)),app.qlim(2,1),app.qlim(2,2));

                %change degree to servo input range(0,1)
                angle2 = (app.qlim(2,2) - angle2)/(app.qlim(2,2) - app.qlim(2,1));
                %clip the data between 0 and 1
                angle2 = clip(angle2,0,1);
                %control servo to right angle
                writePosition(app.JointServo2, angle2);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            app.DhPara = mainApp.DhPara;
            app.qlim = mainApp.qlim;
            if isempty(mainApp.G8Robot) == false
                app.robot = mainApp.G8Robot;
            end
            
            if isempty(mainApp.arduino_hardware) == false
                app.arduino_hardware = mainApp.arduino_hardware;
            end
            
            if isempty(mainApp.JointServo1) == false
                app.JointServo1 = mainApp.JointServo1;
            end
            if isempty(mainApp.JointServo2) == false
                app.JointServo2 = mainApp.JointServo2;
            end
            if isempty(mainApp.JointServo3) == false
                app.JointServo3 = mainApp.JointServo3;
            end
            
            %Set initial joints angle according to pose selection
            poseInx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseInx,1);
            app.Joint2Slider.Value = app.Pose(poseInx,2);
            app.Joint3Slider.Value = app.Pose(poseInx,3);
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            %make sure robot is created already
            if isempty(app.robot) == false
                %update joints angle according to pose selection
                poseinx = str2num(app.PoseDropDown.Value);
                app.Joint1Slider.Value = app.Pose(poseinx,1);
                app.Joint2Slider.Value = app.Pose(poseinx,2);
                app.Joint3Slider.Value = app.Pose(poseinx,3);
                
                %plot the robot and update postion and distance info
                q= deg2rad(app.Pose(poseinx,:));
                app.updateRobot(q);
            else
                msgbox("Robot not created yet")
            end
            
        end

        % Value changed function: Joint1Slider
        function ChangeJoint1(app, event)
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
        end

        % Value changed function: Joint2Slider
        function ChangeJoint2(app, event)
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
        end

        % Value changed function: Joint3Slider
        function ChaengJoint3(app, event)
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
        end

        % Value changed function: XEditField
        function ChangeX(app, event)
            value = app.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function ChangeY(app, event)
            value = app.YEditField.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectHW(app, event)
            try
                app.arduino_hardware = arduino(app.ComPort, 'Uno', 'Libraries', 'Servo');
            catch ME
                warn_msg = ["Connect failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            try
                if isempty(app.arduino_hardware) == false
                    app.JointServo1 = servo(app.arduino_hardware, app.JointServoPin(1), ...
                        'MinPulseDuration', 5.75*10^(-4), 'MaxPulseDuration', 2.46*10^(-3));
                    app.JointServo2 = servo(app.arduino_hardware, app.JointServoPin(2), ...
                        'MinPulseDuration', 5.75*10^(-4), 'MaxPulseDuration', 2.46*10^(-3));
                    app.JointServo3 = servo(app.arduino_hardware, app.JointServoPin(3), ...
                        'MinPulseDuration', 6.4*10^(-4), 'MaxPulseDuration', 2.25*10^(-3));
                end
                
            catch ME
                warn_msg = ["Servo failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            msgbox('Arduino connected');
        end

        % Button pushed function: CreateRobotButton
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            
            % Define links of robot
            L(1) = Revolute('d', app.DhPara(3,1), ...   % link length (Dennavit-Hartenberg notation)
                'a', app.DhPara(1,1), ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.DhPara(2,1)*deg, ...       % link twist (Dennavit-Hartenberg notation)
                'qlim', app.qlim(1,:)*deg, ...          % minimum and maximum joint angle
                'offset',app.DhPara(4,1)*deg ); 
            
            L(2) = Revolute('d', app.DhPara(3,2), ...
                'a', app.DhPara(1,2), ...
                'alpha', app.DhPara(2,2)*deg, ...
                'qlim', app.qlim(2,:)*deg, ...
                'offset',app.DhPara(4,2)*deg );
            
            L(3) = Revolute('d', app.DhPara(3,3), ...
                'a', app.DhPara(1,3), ...
                'alpha', app.DhPara(2,3)*deg,  ...
                'qlim', app.qlim(3,:)*deg, ...
                'offset',app.DhPara(4,3)*deg  );

            %Build the robot
            app.robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(app.DhPara(5,1), app.DhPara(5,2),app.DhPara(5,3)));
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.TestRobotMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: RangeTestButton
        function TestRange(app, event)
            if isempty(app.qlim) == false
                for angle1 = deg2rad([app.qlim(1,1):10:app.qlim(1,2)])
                    for angle2 = deg2rad([app.qlim(2,1):10:app.qlim(2,2)])
                        app.robot.plot([angle1,angle2,0]);
                        T = app.robot.fkine([angle1,angle2,0]);
                        
                        hold on
                        plot3(T.t(1), T.t(2), 0, '.','Color','red')
                        
                        if isempty(app.JointServo1) == false
                            writeposition(app.JointServo1,angle1);
                        end
                        if isempty(app.JointServo2) == false
                            writeposition(app.JointServo2,angle2);
                        end
                    end
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PoseDropDownLabel = uilabel(app.UIFigure);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.UIFigure);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = '1';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-112 90];
            app.Joint1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint1, true);
            app.Joint1Slider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-90 112];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 60 90 100];
            app.Joint2Slider.MajorTickLabels = {'-90', '-60', '-30', '0', '30', '60', '90'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint2, true);
            app.Joint2Slider.MinorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [0 165];
            app.Joint3Slider.MajorTicks = [0 30 60 90 120 150];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @ChaengJoint3, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeX, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeY, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.Editable = 'off';
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotButton.Text = 'CreateRobot';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectHW, true);
            app.ConnectButton.Text = 'Connect';
            app.RangeTestButton = uibutton(app.UIFigure, 'push');
            app.RangeTestButton.ButtonPushedFcn = createCallbackFcn(app, @TestRange, true);
            app.RangeTestButton.Text = 'RangeTest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestRobotDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end